classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        G4SButton  matlab.ui.control.Button
        B4Button   matlab.ui.control.Button
        A4SButton  matlab.ui.control.Button
        A4Button   matlab.ui.control.Button
        G4Button   matlab.ui.control.Button
        F4SButton  matlab.ui.control.Button
        F4Button   matlab.ui.control.Button
        E4Button   matlab.ui.control.Button
        D4SButton  matlab.ui.control.Button
        D4Button   matlab.ui.control.Button
        C4SButton  matlab.ui.control.Button
        C4Button   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: C4Button
        function C4(app, event)

            note = adnote(0.5, 40);
            sound(note, 44100);
        end

        % Button pushed function: C4SButton
        function C4S(app, event)
            note = adnote(0.5, 41);
            sound(note, 44100);
        end

        % Button pushed function: D4Button
        function D4(app, event)
             note = adnote(0.5, 42);
            sound(note, 44100);
        end

        % Button pushed function: D4SButton
        function D4S(app, event)
            note = adnote(0.5, 43);
            sound(note, 44100);
        end

        % Button pushed function: E4Button
        function E4(app, event)
            note = adnote(0.5, 44);
            sound(note, 44100);
        end

        % Button pushed function: F4Button
        function F4(app, event)
            note = adnote(0.5, 45);
            sound(note, 44100);
        end

        % Button pushed function: F4SButton
        function F4S(app, event)
            note = adnote(0.5, 46);
            sound(note, 44100);
        end

        % Button pushed function: G4Button
        function G4(app, event)
            note = adnote(0.5, 47);
            sound(note, 44100);
        end

        % Button pushed function: A4Button
        function A4(app, event)
            note = adnote(0.5, 49);
            sound(note, 44100);
        end

        % Button pushed function: A4SButton
        function A4S(app, event)
            note = adnote(0.5, 50);
            sound(note, 44100);
        end

        % Button pushed function: B4Button
        function B4(app, event)
            note = adnote(0.5, 51);
            sound(note, 44100);
        end

        % Button pushed function: G4SButton
        function G4S(app, event)
            note = adnote(0.5, 48);
            sound(note, 44100);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'a'
                    C4(app, event);
                case 'w'
                    C4S(app, event);
                case 's'
                    D4(app, event);
                case 'e'
                    D4S(app, event);
                    case 'd'
                    E4(app, event);
                    case 'f'
                    F4(app, event);
                    case 't'
                    F4S(app, event);
                    case 'g'
                    G4(app, event);
                    case 'y'
                    G4S(app, event);
                    case 'h'
                    A4(app, event);
                    case 'u'
                    A4S(app, event);
                    case 'j'
                    B4(app, event);

        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.C4Button = uibutton(app.UIFigure, 'push');
            app.C4Button.ButtonPushedFcn = createCallbackFcn(app, @C4, true);
            app.C4Button.Tag = 'C4';
            app.C4Button.Text = 'C4';
            app.C4SButton = uibutton(app.UIFigure, 'push');
            app.C4SButton.ButtonPushedFcn = createCallbackFcn(app, @C4S, true);
            app.C4SButton.Tag = 'C4S';
            app.C4SButton.Text = 'C4S';
            app.D4Button = uibutton(app.UIFigure, 'push');
            app.D4Button.ButtonPushedFcn = createCallbackFcn(app, @D4, true);
            app.D4Button.Tag = 'D4';
            app.D4Button.Text = 'D4';
            app.D4SButton = uibutton(app.UIFigure, 'push');
            app.D4SButton.ButtonPushedFcn = createCallbackFcn(app, @D4S, true);
            app.D4SButton.Tag = 'D4S';
            app.D4SButton.Text = 'D4S';
            app.E4Button = uibutton(app.UIFigure, 'push');
            app.E4Button.ButtonPushedFcn = createCallbackFcn(app, @E4, true);
            app.E4Button.Tag = 'E4';
            app.E4Button.Text = 'E4';
            app.F4Button = uibutton(app.UIFigure, 'push');
            app.F4Button.ButtonPushedFcn = createCallbackFcn(app, @F4, true);
            app.F4Button.Tag = 'F4';
            app.F4Button.Text = 'F4';
            app.F4SButton = uibutton(app.UIFigure, 'push');
            app.F4SButton.ButtonPushedFcn = createCallbackFcn(app, @F4S, true);
            app.F4SButton.Tag = 'F4S';
            app.F4SButton.Text = 'F4S';
            app.G4Button = uibutton(app.UIFigure, 'push');
            app.G4Button.ButtonPushedFcn = createCallbackFcn(app, @G4, true);
            app.G4Button.Tag = 'G4';
            app.G4Button.Text = 'G4';
            app.A4Button = uibutton(app.UIFigure, 'push');
            app.A4Button.ButtonPushedFcn = createCallbackFcn(app, @A4, true);
            app.A4Button.Tag = 'A4';
            app.A4Button.Text = 'A4';
            app.A4SButton = uibutton(app.UIFigure, 'push');
            app.A4SButton.ButtonPushedFcn = createCallbackFcn(app, @A4S, true);
            app.A4SButton.Tag = 'A4S';
            app.A4SButton.Text = 'A4S';
            app.B4Button = uibutton(app.UIFigure, 'push');
            app.B4Button.ButtonPushedFcn = createCallbackFcn(app, @B4, true);
            app.B4Button.Tag = 'B4';
            app.B4Button.Text = 'B4';
            app.G4SButton = uibutton(app.UIFigure, 'push');
            app.G4SButton.ButtonPushedFcn = createCallbackFcn(app, @G4S, true);
            app.G4SButton.Tag = 'G4S';
            app.G4SButton.Text = 'G4S';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end